classdef HELP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        IMG7                  matlab.ui.control.Image
        DELET1                matlab.ui.control.EditField
        IMG5                  matlab.ui.control.Image
        IMG4                  matlab.ui.control.Image
        IMG3                  matlab.ui.control.Image
        CHOOSETHEERRORLabel   matlab.ui.control.Label
        IMG2                  matlab.ui.control.Image
        IMG1                  matlab.ui.control.Image
        OptionsDropDown       matlab.ui.control.DropDown
        OptionsDropDownLabel  matlab.ui.control.Label
        RightPanel            matlab.ui.container.Panel
        IMG6                  matlab.ui.control.Image
        M4B                   matlab.ui.control.Label
        DELET                 matlab.ui.control.EditField
        ERROR1C2              matlab.ui.control.Label
        ERROR1C1              matlab.ui.control.Label
        ERROR1T1              matlab.ui.control.Label
        EditField4            matlab.ui.control.EditField
        ERROR1                matlab.ui.control.Label
        ERROR8C2              matlab.ui.control.Label
        ERROR8C1              matlab.ui.control.Label
        ERROR8T1              matlab.ui.control.Label
        EditField3            matlab.ui.control.EditField
        ERROR0C2              matlab.ui.control.Label
        ERROR0C1              matlab.ui.control.Label
        ERROR0T1              matlab.ui.control.Label
        EditField2            matlab.ui.control.EditField
        E102                  matlab.ui.control.Image
        ERROR102T2            matlab.ui.control.Label
        ERROR102C2            matlab.ui.control.Label
        ERROR102C1            matlab.ui.control.Label
        ERROR102T1            matlab.ui.control.Label
        EditField1            matlab.ui.control.EditField
        ERROR101C5            matlab.ui.control.Label
        ERROR101C4            matlab.ui.control.Label
        ERROR101C3            matlab.ui.control.Label
        ERROR101T2            matlab.ui.control.Label
        ERROR101C2            matlab.ui.control.Label
        ERROR101C1            matlab.ui.control.Label
        ERROR101T1            matlab.ui.control.Label
        EditField             matlab.ui.control.EditField
        ERRORSIDE4C           matlab.ui.control.Label
        ERRORSIDE4T           matlab.ui.control.Label
        ERRORSIDE3C           matlab.ui.control.Label
        ERRORSIDE3T           matlab.ui.control.Label
        ERRORSIDE2C           matlab.ui.control.Label
        ERRORSIDE2T           matlab.ui.control.Label
        ERRORSIDE1C           matlab.ui.control.Label
        ERRORSIDE1T           matlab.ui.control.Label
        ERROR8                matlab.ui.control.Label
        ERROR0                matlab.ui.control.Label
        ERROR102              matlab.ui.control.Label
        ERROR101              matlab.ui.control.Label
        ERRORSIDE             matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;


            switch value
                case '1'
                    if value == '1'
                        app.M4B.Visible ="on";
                        app.IMG1.Visible='on';
                        app.IMG6.Visible='on';
                        app.IMG7.Visible='on';
                        app.DELET.Visible="on";
                        app.DELET1.Visible="on";
                    end

                    
                case '2'
                    if value == '2'
                        app.M4B.Visible ="off";
                        app.IMG1.Visible='off';
                        app.IMG2.Visible='on';
                        app.IMG3.Visible='off';
                        app.IMG4.Visible='off';
                        app.IMG5.Visible='off';
                        app.IMG6.Visible='off';
                        app.IMG7.Visible='off';

                        app.DELET.Visible="off";
                        app.DELET1.Visible="off";

                        app.ERROR0.Visible='off';
                        app.ERROR101.Visible='off';
                        app.ERROR102.Visible='off';
                        app.ERROR8.Visible='off';
                        app.ERROR1.Visible='off';
                        app.ERRORSIDE.Visible='on';

                        app.ERRORSIDE1T.Visible='on';
                        app.ERRORSIDE1C.Visible='on';
                        app.ERRORSIDE2T.Visible='on';
                        app.ERRORSIDE2C.Visible='on';
                        app.ERRORSIDE3T.Visible='on';
                        app.ERRORSIDE3C.Visible='on';
                        app.ERRORSIDE4T.Visible='on';
                        app.ERRORSIDE4C.Visible='on';

                        app.ERROR101T1.Visible ="off";
                        app.ERROR101T2.Visible ="off";
                        app.ERROR101C1.Visible ="off";
                        app.ERROR101C2.Visible ="off";
                        app.ERROR101C3.Visible ="off";
                        app.ERROR101C4.Visible ="off";
                        app.ERROR101C5.Visible ="off";
                        app.EditField.Visible="off";

                        app.ERROR102T1.Visible ="off";
                        app.ERROR102T2.Visible ="off";
                        app.ERROR102C1.Visible ="off";
                        app.ERROR102C2.Visible ="off";
                        app.EditField1.Visible="off";
                        app.E102.Visible="off";

                        app.ERROR0T1.Visible='off';
                        app.ERROR0C1.Visible='off';
                        app.ERROR0C2.Visible='off';
                        app.EditField2.Visible='off';


                        app.ERROR8T1.Visible='off';
                        app.ERROR8C1.Visible='off';
                        app.ERROR8C2.Visible='off';
                        app.EditField3.Visible='off';

                        app.ERROR1T1.Visible='off';
                        app.ERROR1C1.Visible='off';
                        app.ERROR1C2.Visible='off';
                        app.EditField4.Visible='off';

                    end
                    
                case '3'
                    if value == '3'
                        app.M4B.Visible ="off";
                        app.IMG1.Visible='off';
                        app.IMG2.Visible='on';
                        app.IMG3.Visible='off';
                        app.IMG4.Visible='off';
                        app.IMG5.Visible='off';
                        app.IMG6.Visible='off';
                        app.IMG7.Visible='off';

                        app.DELET.Visible="off";
                        app.DELET1.Visible="off";

                        app.ERROR0.Visible='off';
                        app.ERROR101.Visible='on';
                        app.ERROR102.Visible='off';
                        app.ERROR8.Visible='off';
                        app.ERROR1.Visible='off';
                        app.ERRORSIDE.Visible='off';

                        app.ERRORSIDE1T.Visible='off';
                        app.ERRORSIDE1C.Visible='off';
                        app.ERRORSIDE2T.Visible='off';
                        app.ERRORSIDE2C.Visible='off';
                        app.ERRORSIDE3T.Visible='off';
                        app.ERRORSIDE3C.Visible='off';
                        app.ERRORSIDE4T.Visible='off';
                        app.ERRORSIDE4C.Visible='off';

                        app.ERROR101T1.Visible ="on";
                        app.ERROR101T2.Visible ="on";
                        app.ERROR101C1.Visible ="on";
                        app.ERROR101C2.Visible ="on";
                        app.ERROR101C3.Visible ="on";
                        app.ERROR101C4.Visible ="on";
                        app.ERROR101C5.Visible ="on";
                        app.EditField.Visible="off";

                        app.ERROR102T1.Visible ="off";
                        app.ERROR102T2.Visible ="off";
                        app.ERROR102C1.Visible ="off";
                        app.ERROR102C2.Visible ="off";
                        app.EditField1.Visible="off";
                        app.E102.Visible="off";

                        app.ERROR0T1.Visible='off';
                        app.ERROR0C1.Visible='off';
                        app.ERROR0C2.Visible='off';
                        app.EditField2.Visible='off';

                        app.ERROR8T1.Visible='off';
                        app.ERROR8C1.Visible='off';
                        app.ERROR8C2.Visible='off';
                        app.EditField3.Visible='off';

                        app.ERROR1T1.Visible='off';
                        app.ERROR1C1.Visible='off';
                        app.ERROR1C2.Visible='off';
                        app.EditField4.Visible='off';

                    end

                case '4'
                    if value == '4'
                        app.M4B.Visible ="off";
                        app.IMG1.Visible='off';
                        app.IMG2.Visible='off';
                        app.IMG3.Visible='on';
                        app.IMG4.Visible='off';
                        app.IMG5.Visible='off';
                        app.IMG6.Visible='off';
                        app.IMG7.Visible='off';

                        app.DELET.Visible="off";
                        app.DELET1.Visible="off";

                        app.ERROR0.Visible='off';
                        app.ERROR101.Visible='off';
                        app.ERROR102.Visible='on';
                        app.ERROR8.Visible='off';
                        app.ERROR1.Visible='off';
                        app.ERRORSIDE.Visible='off';

                        app.ERRORSIDE1T.Visible='off';
                        app.ERRORSIDE1C.Visible='off';
                        app.ERRORSIDE2T.Visible='off';
                        app.ERRORSIDE2C.Visible='off';
                        app.ERRORSIDE3T.Visible='off';
                        app.ERRORSIDE3C.Visible='off';
                        app.ERRORSIDE4T.Visible='off';
                        app.ERRORSIDE4C.Visible='off';

                        app.ERROR101T1.Visible ="off";
                        app.ERROR101T2.Visible ="off";
                        app.ERROR101C1.Visible ="off";
                        app.ERROR101C2.Visible ="off";
                        app.ERROR101C3.Visible ="off";
                        app.ERROR101C4.Visible ="off";
                        app.ERROR101C5.Visible ="off";
                        app.EditField.Visible="off";

                        app.ERROR102T1.Visible ="on";
                        app.ERROR102T2.Visible ="on";
                        app.ERROR102C1.Visible ="on";
                        app.ERROR102C2.Visible ="on";
                        app.EditField1.Visible="off";
                        app.E102.Visible="on";

                        app.ERROR0T1.Visible='off';
                        app.ERROR0C1.Visible='off';
                        app.ERROR0C2.Visible='off';
                        app.EditField2.Visible='off';

                        app.ERROR8T1.Visible='off';
                        app.ERROR8C1.Visible='off';
                        app.ERROR8C2.Visible='off';
                        app.EditField3.Visible='off';
                    
                        app.ERROR1T1.Visible='off';
                        app.ERROR1C1.Visible='off';
                        app.ERROR1C2.Visible='off';
                        app.EditField4.Visible='off';

                    end  
                case '5'
                    if value == '5'
                        app.M4B.Visible ="off";
                        app.IMG1.Visible='off';
                        app.IMG2.Visible='off';
                        app.IMG3.Visible='off';
                        app.IMG4.Visible='on';
                        app.IMG5.Visible='off';
                        app.IMG6.Visible='off';
                        app.IMG7.Visible='off';

                        app.DELET.Visible="off";
                        app.DELET1.Visible="off";

                        app.ERROR0.Visible='on';
                        app.ERROR101.Visible='off';
                        app.ERROR102.Visible='off';
                        app.ERROR8.Visible='off';
                        app.ERROR1.Visible='off';
                        app.ERRORSIDE.Visible='off';

                        app.ERRORSIDE1T.Visible='off';
                        app.ERRORSIDE1C.Visible='off';
                        app.ERRORSIDE2T.Visible='off';
                        app.ERRORSIDE2C.Visible='off';
                        app.ERRORSIDE3T.Visible='off';
                        app.ERRORSIDE3C.Visible='off';
                        app.ERRORSIDE4T.Visible='off';
                        app.ERRORSIDE4C.Visible='off';

                        app.ERROR101T1.Visible ="off";
                        app.ERROR101T2.Visible ="off";
                        app.ERROR101C1.Visible ="off";
                        app.ERROR101C2.Visible ="off";
                        app.ERROR101C3.Visible ="off";
                        app.ERROR101C4.Visible ="off";
                        app.ERROR101C5.Visible ="off";
                        app.EditField.Visible="off";

                        app.ERROR102T1.Visible ="off";
                        app.ERROR102T2.Visible ="off";
                        app.ERROR102C1.Visible ="off";
                        app.ERROR102C2.Visible ="off";
                        app.EditField1.Visible="off";
                        app.E102.Visible="off";

                        app.ERROR0T1.Visible='on';
                        app.ERROR0C1.Visible='on';
                        app.ERROR0C2.Visible='on';
                        app.EditField2.Visible='off';

                        app.ERROR8T1.Visible='off';
                        app.ERROR8C1.Visible='off';
                        app.ERROR8C2.Visible='off';
                        app.EditField3.Visible='off';

                        app.ERROR1T1.Visible='off';
                        app.ERROR1C1.Visible='off';
                        app.ERROR1C2.Visible='off';
                        app.EditField4.Visible='off';


                    end
                case '6'
                    if value == '6'
                        app.M4B.Visible ="off";
                        app.IMG1.Visible='off';
                        app.IMG2.Visible='on';
                        app.IMG3.Visible='off';
                        app.IMG4.Visible='off';
                        app.IMG5.Visible='off';
                        app.IMG6.Visible='off';
                        app.IMG7.Visible='off';

                        app.DELET.Visible="off";
                        app.DELET1.Visible="off";

                        app.ERROR0.Visible='off';
                        app.ERROR101.Visible='off';
                        app.ERROR102.Visible='off';
                        app.ERROR8.Visible='off';
                        app.ERROR1.Visible='on';
                        app.ERRORSIDE.Visible='off';

                        app.ERRORSIDE1T.Visible='off';
                        app.ERRORSIDE1C.Visible='off';
                        app.ERRORSIDE2T.Visible='off';
                        app.ERRORSIDE2C.Visible='off';
                        app.ERRORSIDE3T.Visible='off';
                        app.ERRORSIDE3C.Visible='off';
                        app.ERRORSIDE4T.Visible='off';
                        app.ERRORSIDE4C.Visible='off';

                        app.ERROR101T1.Visible ="off";
                        app.ERROR101T2.Visible ="off";
                        app.ERROR101C1.Visible ="off";
                        app.ERROR101C2.Visible ="off";
                        app.ERROR101C3.Visible ="off";
                        app.ERROR101C4.Visible ="off";
                        app.ERROR101C5.Visible ="off";
                        app.EditField.Visible="off";

                        app.ERROR102T1.Visible ="off";
                        app.ERROR102T2.Visible ="off";
                        app.ERROR102C1.Visible ="off";
                        app.ERROR102C2.Visible ="off";
                        app.EditField1.Visible="off";
                        app.E102.Visible="off";

                        app.ERROR0T1.Visible='off';
                        app.ERROR0C1.Visible='off';
                        app.ERROR0C2.Visible='off';
                        app.EditField2.Visible='off';

                        app.ERROR8T1.Visible='off';
                        app.ERROR8C1.Visible='off';
                        app.ERROR8C2.Visible='off';
                        app.EditField3.Visible='off';

                        app.ERROR1T1.Visible='on';
                        app.ERROR1C1.Visible='on';
                        app.ERROR1C2.Visible='on';
                        app.EditField4.Visible='off';


                    end


                case '7'
                    if value == '7'
                        app.M4B.Visible ="off";
                        app.IMG1.Visible='off';
                        app.IMG2.Visible='off';
                        app.IMG3.Visible='off';
                        app.IMG4.Visible='off';
                        app.IMG5.Visible='on';
                        app.IMG6.Visible='off';
                        app.IMG7.Visible='off';

                        app.DELET.Visible="off";
                        app.DELET1.Visible="off";

                        app.ERROR0.Visible='off';
                        app.ERROR101.Visible='off';
                        app.ERROR102.Visible='off';
                        app.ERROR8.Visible='on';
                        app.ERROR1.Visible='off';
                        app.ERRORSIDE.Visible='off';

                        app.ERRORSIDE1T.Visible='off';
                        app.ERRORSIDE1C.Visible='off';
                        app.ERRORSIDE2T.Visible='off';
                        app.ERRORSIDE2C.Visible='off';
                        app.ERRORSIDE3T.Visible='off';
                        app.ERRORSIDE3C.Visible='off';
                        app.ERRORSIDE4T.Visible='off';
                        app.ERRORSIDE4C.Visible='off';

                        app.ERROR101T1.Visible ="off";
                        app.ERROR101T2.Visible ="off";
                        app.ERROR101C1.Visible ="off";
                        app.ERROR101C2.Visible ="off";
                        app.ERROR101C3.Visible ="off";
                        app.ERROR101C4.Visible ="off";
                        app.ERROR101C5.Visible ="off";
                        app.EditField.Visible="off";

                        app.ERROR102T1.Visible ="off";
                        app.ERROR102T2.Visible ="off";
                        app.ERROR102C1.Visible ="off";
                        app.ERROR102C2.Visible ="off";
                        app.EditField1.Visible="off";
                        app.E102.Visible="off";

                        app.ERROR0T1.Visible='off';
                        app.ERROR0C1.Visible='off';
                        app.ERROR0C2.Visible='off';
                        app.EditField2.Visible='off';

                        app.ERROR8T1.Visible='on';
                        app.ERROR8C1.Visible='on';
                        app.ERROR8C2.Visible='on';
                        app.EditField3.Visible='off';

                        app.ERROR1T1.Visible='off';
                        app.ERROR1C1.Visible='off';
                        app.ERROR1C2.Visible='off';
                        app.EditField4.Visible='off';
                        

                    end


            end


        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OptionsDropDownLabel = uilabel(app.LeftPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.LeftPanel);
            app.OptionsDropDown.Items = {'Select', 'Error "SIDE"', 'Error #101', 'Error #102', 'Error #000000', 'Error #100010', 'Error #800080'};
            app.OptionsDropDown.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = '1';
            app.IMG1 = uiimage(app.LeftPanel);
            app.IMG1.ImageSource = fullfile(pathToMLAPP, 'IMG', 'IMG_Welcome.png');
            app.IMG2 = uiimage(app.LeftPanel);
            app.IMG2.ImageSource = fullfile(pathToMLAPP, 'MechanisImag.PNG');
            app.CHOOSETHEERRORLabel = uilabel(app.LeftPanel);
            app.CHOOSETHEERRORLabel.Text = 'CHOOSE THE ERROR';
            app.IMG3 = uiimage(app.LeftPanel);
            app.IMG3.ImageSource = fullfile(pathToMLAPP, 'icono_roptura.png');
            app.IMG4 = uiimage(app.LeftPanel);
            app.IMG4.ImageSource = fullfile(pathToMLAPP, 'MechanisImage0.png');
            app.IMG5 = uiimage(app.LeftPanel);
            app.IMG5.ImageSource = fullfile(pathToMLAPP, 'MechanisImage8.png');
            app.DELET1 = uieditfield(app.LeftPanel, 'text');
            app.IMG7 = uiimage(app.LeftPanel);
            app.IMG7.ImageSource = fullfile(pathToMLAPP, 'IMG', 'IMG_Welcome.png');
            app.RightPanel = uipanel(app.GridLayout);
            app.ERRORSIDE = uilabel(app.RightPanel);
            app.ERRORSIDE.Text = 'ERROR ''SIDE''';
            app.ERROR101 = uilabel(app.RightPanel);
            app.ERROR101.Text = 'ERROR #101';
            app.ERROR102 = uilabel(app.RightPanel);
            app.ERROR102.Text = 'ERROR #102';
            app.ERROR0 = uilabel(app.RightPanel);
            app.ERROR0.Text = 'ERROR #000000';
            app.ERROR8 = uilabel(app.RightPanel);
            app.ERROR8.Text = 'ERROR #800080';
            app.ERRORSIDE1T = uilabel(app.RightPanel);
            app.ERRORSIDE1T.Text = 'ERROR ''SIDE 1''';
            app.ERRORSIDE1C = uilabel(app.RightPanel);
            app.ERRORSIDE1C.Text = {'This error occurs if more than the value of ''SIDE 1'' is entered. '; 'DON''T WORRY. ''It is the fixed bar'''; 'The application will delete this value and replace it with a ''0'', '; 'because it is an exorbitant or erroneous value. '};
            app.ERRORSIDE2T = uilabel(app.RightPanel);
            app.ERRORSIDE2T.Text = 'ERROR ''SIDE 2''';
            app.ERRORSIDE2C = uilabel(app.RightPanel);
            app.ERRORSIDE2C.Text = {'This error occurs if more than the value of ''SIDE 2'' is entered. '; 'DON''T WORRY.'; 'The application will delete this value and replace it with a ''0'', '; 'because it is an exorbitant or erroneous value.'};
            app.ERRORSIDE3T = uilabel(app.RightPanel);
            app.ERRORSIDE3T.Text = 'ERROR ''SIDE 3''';
            app.ERRORSIDE3C = uilabel(app.RightPanel);
            app.ERRORSIDE3C.Text = {'This error occurs if more than the value of ''SIDE 3'' is entered. '; 'DON''T WORRY.'; 'The application will delete this value and replace it with a ''0'', '; 'because it is an exorbitant or erroneous value.'};
            app.ERRORSIDE4T = uilabel(app.RightPanel);
            app.ERRORSIDE4T.Text = 'ERROR ''SIDE 4''';
            app.ERRORSIDE4C = uilabel(app.RightPanel);
            app.ERRORSIDE4C.Text = {'This error occurs if more than the value of ''SIDE 4'' is entered. '; 'DON''T WORRY.'; 'The application will delete this value and replace it with a ''0'', '; 'because it is an exorbitant or erroneous value.'};
            app.EditField = uieditfield(app.RightPanel, 'text');
            app.ERROR101T1 = uilabel(app.RightPanel);
            app.ERROR101T1.Text = 'NON-COMPLIANCE WITH THE GRASHOF LAW';
            app.ERROR101C1 = uilabel(app.RightPanel);
            app.ERROR101C1.Text = {'The ''101'' error develops because the ''GRASHOF''S LAW'' is '; 'not fulfilled, as all calculations are based on it.'; 'You are recommended to use other data because the program'; ' is unable to correct them automatically.'};
            app.ERROR101C2 = uilabel(app.RightPanel);
            app.ERROR101C2.Text = {'You are advised to look at the following formulas and equations'; ' to check that your mechanism is valid.'};
            app.ERROR101T2 = uilabel(app.RightPanel);
            app.ERROR101T2.Text = 'Formulae & Equations';
            app.ERROR101C3 = uilabel(app.RightPanel);
            app.ERROR101C3.Text = 'SIDE 2 > SIDE 4 > SIDE 1 > SIDE 3';
            app.ERROR101C4 = uilabel(app.RightPanel);
            app.ERROR101C4.Text = 'SIDE 2 + SIDE 3 <= SIDE 1 + SIDE 4';
            app.ERROR101C5 = uilabel(app.RightPanel);
            app.ERROR101C5.Text = 'SIDE 2 + SIDE 3 > SIDE 1 + SIDE 4';
            app.EditField1 = uieditfield(app.RightPanel, 'text');
            app.ERROR102T1 = uilabel(app.RightPanel);
            app.ERROR102T1.Text = '''FRACTURED MECHANISM''';
            app.ERROR102C1 = uilabel(app.RightPanel);
            app.ERROR102C1.Text = {'Error ''102'' is a fatal error, because the mechanism broke in one '; 'of the SIDE or UNIONS. '; ''; 'This fracture is due to the mechanism exceeding its maximum '; 'limit or it was not the right mechanism to simulate.'; ''; 'It is recommended to check whether the data are appropriate for'; ' the type of mechanism. This error is very common in the '; '''DOUBLE-ROCKER'' mechanism. '};
            app.ERROR102C2 = uilabel(app.RightPanel);
            app.ERROR102C2.Text = {'It is recommended to review the angle limits because none of the'; ' SIDE mechanism makes a complete turn. '; 'The application eliminated the angle that is possibly causing the'; ' mechanism to break or fail.'};
            app.ERROR102T2 = uilabel(app.RightPanel);
            app.ERROR102T2.Text = '''INCORRECT ANGLES''';
            app.E102 = uiimage(app.RightPanel);
            app.E102.ImageSource = fullfile(pathToMLAPP, 'icono_x.png');
            app.EditField2 = uieditfield(app.RightPanel, 'text');
            app.ERROR0T1 = uilabel(app.RightPanel);
            app.ERROR0T1.Text = '''INCORRECT MECHANISM''';
            app.ERROR0C1 = uilabel(app.RightPanel);
            app.ERROR0C1.Text = {'If at the moment of performing the simulation in one of the different'; ' mechanisms that do not correspond to the "DOUBLE-CRANK" the '; 'application will throw the error ''000000''.'; ''; 'This is because the data entered in the simulation section does not '; 'correspond to the selected mechanism, which can cause problems '; 'when simulating it.'; ''; 'These problems can be:'};
            app.ERROR0C2 = uilabel(app.RightPanel);
            app.ERROR0C2.Text = {'+ WRONG ANIMATION'; ''; '+ WRONG CALCULATIONS'; ''; '+ OUT OF PHASE SIDES'; ''; '+ FALSE BREAKS OF MECHANISMS '; ''; '+ ABNORMAL MOVEMENTS'};
            app.EditField3 = uieditfield(app.RightPanel, 'text');
            app.ERROR8T1 = uilabel(app.RightPanel);
            app.ERROR8T1.Text = '''INCORRECT MECHANISM''';
            app.ERROR8C1 = uilabel(app.RightPanel);
            app.ERROR8C1.Text = {'If at the moment of performing the simulation in one of the different'; ' mechanisms that do not correspond to the "DOUBLE-ROCKER" the '; 'application will throw the error ''800080''.'; ''; 'This is because the data entered in the simulation section does not '; 'correspond to the selected mechanism, which can cause problems '; 'when simulating it.'; ''; 'These problems can be:'};
            app.ERROR8C2 = uilabel(app.RightPanel);
            app.ERROR8C2.Text = {'+ WRONG ANIMATION'; ''; '+ WRONG CALCULATIONS'; ''; '+ OUT OF PHASE SIDES'; ''; '+ FALSE BREAKS OF MECHANISMS '; ''; '+ ABNORMAL MOVEMENTS'};
            app.ERROR1 = uilabel(app.RightPanel);
            app.ERROR1.Text = 'ERROR #100010';
            app.EditField4 = uieditfield(app.RightPanel, 'text');
            app.ERROR1T1 = uilabel(app.RightPanel);
            app.ERROR1T1.Text = '''INCORRECT MECHANISM''';
            app.ERROR1C1 = uilabel(app.RightPanel);
            app.ERROR1C1.Text = {'If at the moment of performing the simulation in one of the different'; ' mechanisms that do not correspond to the "CRANK & ROCKER" the '; 'application will throw the error ''100010''.'; ''; 'This is because the data entered in the simulation section does not '; 'correspond to the selected mechanism, which can cause problems '; 'when simulating it.'; ''; 'These problems can be:'};
            app.ERROR1C2 = uilabel(app.RightPanel);
            app.ERROR1C2.Text = {'+ WRONG ANIMATION'; ''; '+ WRONG CALCULATIONS'; ''; '+ OUT OF PHASE SIDES'; ''; '+ FALSE BREAKS OF MECHANISMS '; ''; '+ ABNORMAL MOVEMENTS'};
            app.DELET = uieditfield(app.RightPanel, 'text');
            app.M4B = uilabel(app.RightPanel);
            app.M4B.Text = 'M4B';
            app.IMG6 = uiimage(app.RightPanel);
            app.IMG6.ImageSource = fullfile(pathToMLAPP, 'logequip.PNG');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HELP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end